classdef CTECS_quintic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RealtimemodeButtonGroup      matlab.ui.container.ButtonGroup
        PauseButton                  matlab.ui.control.Button
        ErrorButton                  matlab.ui.control.RadioButton
        MeasureButton                matlab.ui.control.RadioButton
        NooflinesEditField           matlab.ui.control.NumericEditField
        NooflinesEditFieldLabel      matlab.ui.control.Label
        ExportButton                 matlab.ui.control.Button
        ESPConsoleEditFieldLabel     matlab.ui.control.Label
        ESPConsoleEditField          matlab.ui.control.EditField
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        EnterButton                  matlab.ui.control.Button
        OutwardSerialEditFieldLabel  matlab.ui.control.Label
        InputxEditField              matlab.ui.control.NumericEditField
        OutwardSerialEditField       matlab.ui.control.EditField
        InputyButtonGroup            matlab.ui.container.ButtonGroup
        REditField_2                 matlab.ui.control.NumericEditField
        REditField_2Label            matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        EditField_2Label             matlab.ui.control.Label
        InputyEditField              matlab.ui.control.NumericEditField
        CircularButton_2             matlab.ui.control.RadioButton
        LinearButton_2               matlab.ui.control.RadioButton
        InputzButtonGroup            matlab.ui.container.ButtonGroup
        REditField_3                 matlab.ui.control.NumericEditField
        REditField_3Label            matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_3Label             matlab.ui.control.Label
        InputzEditField              matlab.ui.control.NumericEditField
        CircularButton_3             matlab.ui.control.RadioButton
        LinearButton_3               matlab.ui.control.RadioButton
        InputxButtonGroup            matlab.ui.container.ButtonGroup
        REditField                   matlab.ui.control.NumericEditField
        REditFieldLabel              matlab.ui.control.Label
        CircularButton               matlab.ui.control.RadioButton
        LinearButton                 matlab.ui.control.RadioButton
        UIAxes3_9                    matlab.ui.control.UIAxes
        UIAxes3_8                    matlab.ui.control.UIAxes
        UIAxes3_7                    matlab.ui.control.UIAxes
        UIAxes3_6                    matlab.ui.control.UIAxes
        UIAxes3_5                    matlab.ui.control.UIAxes
        UIAxes3_4                    matlab.ui.control.UIAxes
        UIAxes3_3                    matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        simulation                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        ESP_serial; % Description
        O = [0 0 0];
        %% Smol dog
        R = 5; %8; %= 0.5;
        r = 6.8; %7.5; % 0.2;
        d = 6; %10; % 0.5;
        h = 12; %45; % 2.5;
        %% Big dog
        % R = 7.5; %8; %= 0.5;
        % r = 4.5; %7.5; % 0.2;
        % d = 10; % 0.5;
        % h = 38; % 2.5;

        alpha = [0 2*pi/3 4*pi/3];
        beta = [0 2*pi/3 4*pi/3];

        A;
        B;
        RA1;
        RA2;
        RA3;
        RB1;
        RB2;
        RB3;
        A1;
        A2;
        A3;

        sample_trajectory;
        sim_theta1;
        sim_theta2;
        sim_theta3;
        sim_cartesian1;
        sim_cartesian2;
        sim_cartesian3;
        sim_XY;
        sim_XZ;

        theta_rec_send = [];
        export; theta_export = [];

        plot_pause = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            focus(app.InputxEditField);
            app.ESP_serial = serialport('COM6', 115200);
            
            %% Initialize calculations
            app.A = [app.R 0 0]';
            app.B = [app.r 0 0]';
            app.RA1 = [cos(app.alpha(1)), -sin(app.alpha(1)), 0;
                  sin(app.alpha(1)),  cos(app.alpha(1)), 0;
                  0,          0, 1];
            app.RA2 = [cos(app.alpha(2)), -sin(app.alpha(2)), 0;
                  sin(app.alpha(2)),  cos(app.alpha(2)), 0;
                  0,          0, 1];
            app.RA3 = [cos(app.alpha(3)), -sin(app.alpha(3)), 0;
                  sin(app.alpha(3)),  cos(app.alpha(3)), 0;
                  0,          0, 1];
            app.RB1 = [cos(app.beta(1)), -sin(app.beta(1)), 0;
                  sin(app.beta(1)),  cos(app.beta(1)), 0;
                  0,          0, 1];
            app.RB2 = [cos(app.beta(2)), -sin(app.beta(2)), 0;
                  sin(app.beta(2)),  cos(app.beta(2)), 0;
                  0,          0, 1];
            app.RB3 = [cos(app.beta(3)), -sin(app.beta(3)), 0;
                  sin(app.beta(3)),  cos(app.beta(3)), 0;
                  0,          0, 1];
            app.A1 = app.RA1*app.A;
            app.A2 = app.RA2*app.A;
            app.A3 = app.RA3*app.A;

            % Initial forward kinematics
                %% Forward Kinematics
                    % Calculate D and E positions
                    C = [app.d,0,0]';
                    RD1 = [cos(0), 0, sin(0);
                           0,  1, 0;
                           -sin(0), 0, cos(0)];
                    RD2 = [cos(0), 0, sin(0);
                           0,  1, 0;
                           -sin(0), 0, cos(0)];
                    RD3 = [cos(0), 0, sin(0);
                           0,  1, 0;
                           -sin(0), 0, cos(0)];
                    C1 = app.A1 +  app.RA1*RD1*C;
                    C2 = app.A2 +  app.RA2*RD2*C;
                    C3 = app.A3 +  app.RA3*RD3*C;

                    % S1,S2,S3
                    u1 = C1 - app.RB1*app.B;
                    u2 = C2 - app.RB2*app.B;
                    u3 = C3 - app.RB3*app.B;
                    pos = parametric_circumcenter(u1,u2,u3,app.h);
                    B1 = pos + app.RB1*app.B;
                    B2 = pos + app.RB2*app.B;
                    B3 = pos + app.RB3*app.B;

            %% Initialize 3D plots
            % Sample trajectory
                hold(app.simulation, 'on');
                app.sample_trajectory = plot3(app.simulation,[0 0],[0 0],[10 18],'k--','LineWidth',1);
            % Frame limit
                offset = 10;
                axis(app.simulation,'equal');
                xlim(app.simulation,[compr(pos(1),C2(1),2)-offset compr(pos(1),C1(1),1)+offset]);
                ylim(app.simulation,[compr(pos(2),C3(2),2)-offset compr(pos(2),C2(2),1)+offset]);
                % zlim(app.simulation,[pos(3)-offset O(3)+offset]);
                % zlim(app.simulation,[app.O(3)-offset -pos(3)+offset]);
            % Base
                % hold(app.simulation, 'off');
                plot3(app.simulation,[app.O(1) app.A1(1)],[app.O(2) app.A1(2)],[app.O(3) app.A1(3)],'LineWidth',3);
                plot3(app.simulation,[app.O(1) app.A2(1)],[app.O(2) app.A2(2)],[app.O(3) app.A2(3)],'LineWidth',3);
                plot3(app.simulation,[app.O(1) app.A3(1)],[app.O(2) app.A3(2)],[app.O(3) app.A3(3)],'LineWidth',3);
                % plot3(app.simulation,[A1(1) C1(1)],[A1(2) C1(2)],[A1(3) C1(3)],'LineWidth',3);
                % plot3(app.simulation,[A2(1) C2(1)],[A2(2) C2(2)],[A2(3) C2(3)],'LineWidth',3);
                % plot3(app.simulation,[A3(1) C3(1)],[A3(2) C3(2)],[A3(3) C3(3)],'LineWidth',3);
            % Biceps
                b1 = plot3(app.simulation,[app.A1(1) C1(1)],[app.A1(2) C1(2)],[app.A1(3) -C1(3)],'LineWidth',3);
                b2 = plot3(app.simulation,[app.A2(1) C2(1)],[app.A2(2) C2(2)],[app.A2(3) -C2(3)],'LineWidth',3);
                b3 = plot3(app.simulation,[app.A3(1) C3(1)],[app.A3(2) C3(2)],[app.A3(3) -C3(3)],'LineWidth',3);        
            % Femurs
                f1 = plot3(app.simulation,[C1(1) B1(1)],[C1(2) B1(2)],[-C1(3) -B1(3)],'LineWidth',3);
                f2 = plot3(app.simulation,[C2(1) B2(1)],[C2(2) B2(2)],[-C2(3) -B2(3)],'LineWidth',3);
                f3 = plot3(app.simulation,[C3(1) B3(1)],[C3(2) B3(2)],[-C3(3) -B3(3)],'LineWidth',3);
                % plot3(app.simulation,[C1(1) B1(1)],[C1(2) B1(2)],[C1(3) B1(3)],'LineWidth',3);
                % plot3(app.simulation,[C2(1) B2(1)],[C2(2) B2(2)],[C2(3) B2(3)],'LineWidth',3);
                % plot3(app.simulation,[C3(1) B3(1)],[C3(2) B3(2)],[C3(3) B3(3)],'LineWidth',3);
            % Position
                p1 = plot3(app.simulation,pos(1),pos(2),-pos(3),'r*');

            %% Initialize time
            ini_sec = second(datetime('now'));
            ini_min = minute(datetime('now'));
            ini_hr = hour(datetime('now'));
            time_ini = ini_hr * 3600 + ini_min *60 + ini_sec;

            %% Initialize 2D plots
            theta1_plot = [];
            theta2_plot = [];
            theta3_plot = [];
            theta1_error_plot = [];
            theta2_error_plot = [];
            theta3_error_plot = [];
            cartesian1_error_plot = [];
            cartesian2_error_plot = [];
            cartesian3_error_plot = [];
            x_plot = [];
            y_plot = [];
            z_plot = [];
            pitch_plot = [];
            roll_plot = [];
            time_plot = [];
            
                %% Real time plots
                % Motor Angles
                t1 = plot(app.UIAxes3_3, [0 0], [0 0],'LineWidth',1);
                t2 = plot(app.UIAxes3_3, [0 0], [0 0], '-.','LineWidth',1);
                t3 = plot(app.UIAxes3_3, [0 0], [0 0],'--','LineWidth',1);
                legend(app.UIAxes3_3,'Motor 1','Motor 2','Motor 3');
    
                te1 = plot(app.UIAxes3_4, [0 0], [0 0],'LineWidth',1);
                te2 = plot(app.UIAxes3_4, [0 0], [0 0], '-.','LineWidth',1);
                te3 = plot(app.UIAxes3_4, [0 0], [0 0],'--','LineWidth',1);
                legend(app.UIAxes3_4,'Motor 1','Motor 2','Motor 3');

                % Cartesian Position
                c1 = plot(app.UIAxes3_8, [0 0], [0 0],'LineWidth',1);
                c2 = plot(app.UIAxes3_8, [0 0], [0 0], '-.','LineWidth',1);
                c3 = plot(app.UIAxes3_8, [0 0], [0 0],'--','LineWidth',1);
                legend(app.UIAxes3_8,'x','y','z');

                ce1 = plot(app.UIAxes3_9, [0 0], [0 0],'LineWidth',1);
                ce2 = plot(app.UIAxes3_9, [0 0], [0 0], '-.','LineWidth',1);
                ce3 = plot(app.UIAxes3_9, [0 0], [0 0],'--','LineWidth',1);
                legend(app.UIAxes3_9,'x','y','z');
    
                % Pitch & Roll
                piro1 = plot(app.UIAxes3_7, [0 0], [0 0],'LineWidth',1);
                piro2 = plot(app.UIAxes3_7, [0 0], [0 0],'LineWidth',1);
                legend(app.UIAxes3_7,'Pitch','Roll');
                
                % XY & XZ planes
                XY_trajectory = plot(app.UIAxes3_5, [0 0], [0 0],'LineWidth',1);
                XZ_trajectory = plot(app.UIAxes3_6, [0 0], [0 0],'LineWidth',1);
                axis(app.UIAxes3_5,'equal');
                axis(app.UIAxes3_6,'equal');
    
                %% Simulation plots
                app.sim_theta1 = plot(app.UIAxes3, [0 0], [0 0],'LineWidth',1);
                app.sim_theta2 = plot(app.UIAxes3, [0 0], [0 0], '-.','LineWidth',1);
                app.sim_theta3 = plot(app.UIAxes3, [0 0], [0 0], '--','LineWidth',1);
                legend(app.UIAxes3,'Motor 1','Motor 2','Motor 3');
                
                app.sim_cartesian1 = plot(app.UIAxes3_2, [0 0], [0 0],'LineWidth',1);
                app.sim_cartesian2 = plot(app.UIAxes3_2, [0 0], [0 0], '-.','LineWidth',1);
                app.sim_cartesian3 = plot(app.UIAxes3_2, [0 0], [0 0], '--','LineWidth',1);
                legend(app.UIAxes3_2,'x','y','z');
    
                app.sim_XY = plot(app.UIAxes3_5, [0 0], [0 0],'LineWidth',1);
                app.sim_XZ= plot(app.UIAxes3_6, [0 0], [0 0],'LineWidth',1);
                legend(app.UIAxes3_5,'Real-time','Simulation');
                legend(app.UIAxes3_6,'Real-time','Simulation');
            
            %% Read data from ESP
            init_delay = 0;
            while(1)
                data = readline(app.ESP_serial);
                app.ESPConsoleEditField.Value = data;
                ini_sec = second(datetime('now'));
                ini_min = minute(datetime('now'));
                ini_hr = hour(datetime('now'));
                time_now = ini_hr * 3600 + ini_min *60 + ini_sec;
                time = time_now - time_ini;
                
                if ~isempty(data) 
                    if init_delay > 10
                        % Extract data
                        theta_sw = [0,0,0];
                        theta_rec = [0,0,0];
                        theta_error = [0,0,0];
                        angle_data = str2double(regexp(data, '[-+]?\d*\.?\d+', 'match'));  % Smol dog
                        theta_rec(1) = angle_data(8);   
                        theta_rec(2) = angle_data(10);
                        theta_rec(3) = angle_data(12);
                        app.theta_rec_send(1) = angle_data(8);   
                        app.theta_rec_send(2) = angle_data(10);
                        app.theta_rec_send(3) = angle_data(12);
                        theta_error(1) = angle_data(2) - angle_data(8);
                        theta_error(2) = angle_data(4) - angle_data(10);
                        theta_error(3) = angle_data(6) - angle_data(12);
                        % - 15;  % BIg dog
                        pitch = angle_data(13);
                        roll = angle_data(14);
    
                        if ((theta_sw(1)~=angle_data(2)) || (theta_sw(2)~=angle_data(4)) || (theta_sw(3)~=angle_data(6))) %% if position changes
                        theta_sw(1) = deg2rad(angle_data(2));
                        theta_sw(2) = deg2rad(angle_data(4));
                        theta_sw(3) = deg2rad(angle_data(6));
                        % - 15;  % BIg dog
    
                        %% Forward Kinematics
                        % Calculate D and E positions
                        C = [app.d,0,0]';
                        RD1 = [cos(theta_sw(1)), 0, sin(theta_sw(1));
                               0,  1, 0;
                               -sin(theta_sw(1)), 0, cos(theta_sw(1))];
                        RD2 = [cos(theta_sw(2)), 0, sin(theta_sw(2));
                               0,  1, 0;
                               -sin(theta_sw(2)), 0, cos(theta_sw(2))];
                        RD3 = [cos(theta_sw(3)), 0, sin(theta_sw(3));
                               0,  1, 0;
                               -sin(theta_sw(3)), 0, cos(theta_sw(3))];
                        C1 = app.A1 +  app.RA1*RD1*C;
                        C2 = app.A2 +  app.RA2*RD2*C;
                        C3 = app.A3 +  app.RA3*RD3*C;
    
                        % S1,S2,S3
                        u1 = C1 - app.RB1*app.B;
                        u2 = C2 - app.RB2*app.B;
                        u3 = C3 - app.RB3*app.B;
    
                        pos = parametric_circumcenter(u1,u2,u3,app.h);
                        B1 = pos + app.RB1*app.B;
                        B2 = pos + app.RB2*app.B;
                        B3 = pos + app.RB3*app.B;
    
                        %% 3D Plot Update
                        % % Plot 3D data
                        % Biceps
                            set(b1, 'XData', [app.A1(1), C1(1)], ...
                                    'YData', [app.A1(2), C1(2)], ...
                                    'ZData', [app.A1(3), -C1(3)])
                            set(b2, 'XData', [app.A2(1), C2(1)], ...
                                    'YData', [app.A2(2), C2(2)], ...
                                    'ZData', [app.A2(3), -C2(3)])
                            set(b3, 'XData', [app.A3(1), C3(1)], ...
                                    'YData', [app.A3(2), C3(2)], ...
                                    'ZData', [app.A3(3), -C3(3)])
                        % Femurs
                            set(f1, 'XData', [C1(1), B1(1)], ...
                                    'YData', [C1(2), B1(2)], ...
                                    'ZData', [-C1(3), -B1(3)])
                            set(f2, 'XData', [C2(1), B2(1)], ...
                                    'YData', [C2(2), B2(2)], ...
                                    'ZData', [-C2(3), -B2(3)])
                            set(f3, 'XData', [C3(1), B3(1)], ...
                                    'YData', [C3(2), B3(2)], ...
                                    'ZData', [-C3(3), -B3(3)])
                        % Position
                            set(p1, 'XData', pos(1), ...
                                    'YData', pos(2), ...
                                    'ZData', -pos(3))
                            % plot3(app.simulation,pos(1),pos(2),-pos(3),'r*');
                            drawnow limitrate;
    
                        
                        end

                        %% 2D Plot Data Update
                        theta1_plot = cat(2,theta1_plot,theta_rec(1));
                        theta2_plot = cat(2,theta2_plot,theta_rec(2));
                        theta3_plot = cat(2,theta3_plot,theta_rec(3));
                        theta1_error_plot = cat(2,theta1_error_plot,theta_error(1));
                        theta2_error_plot = cat(2,theta2_error_plot,theta_error(2));
                        theta3_error_plot = cat(2,theta3_error_plot,theta_error(3));
                        pitch_plot = cat(2,pitch_plot,pitch);
                        roll_plot = cat(2,roll_plot,roll);
                        
                        time_plot = cat(2,time_plot,time);


                        %% Export to EXCEL
                        if app.export == 1
                            writematrix(time_plot','Theta_data.xlsx','Sheet',1,'Range','H2'); 
                            writematrix(theta1_plot','Theta_data.xlsx','Sheet',1,'Range','I2');
                            writematrix(theta2_plot','Theta_data.xlsx','Sheet',1,'Range','J2');
                            writematrix(theta3_plot','Theta_data.xlsx','Sheet',1,'Range','K2');
                            app.export = 0;
                        end
                        
    
                        %% Forward Kinematics for Plane Trajectory
                        theta_sw(1) = deg2rad(angle_data(8));
                        theta_sw(2) = deg2rad(angle_data(10));
                        theta_sw(3) = deg2rad(angle_data(12));
    
                        % Calculate D and E positions
                        C = [app.d,0,0]';
                        RD1 = [cos(theta_sw(1)), 0, sin(theta_sw(1));
                               0,  1, 0;
                               -sin(theta_sw(1)), 0, cos(theta_sw(1))];
                        RD2 = [cos(theta_sw(2)), 0, sin(theta_sw(2));
                               0,  1, 0;
                               -sin(theta_sw(2)), 0, cos(theta_sw(2))];
                        RD3 = [cos(theta_sw(3)), 0, sin(theta_sw(3));
                               0,  1, 0;
                               -sin(theta_sw(3)), 0, cos(theta_sw(3))];
                        C1 = app.A1 +  app.RA1*RD1*C;
                        C2 = app.A2 +  app.RA2*RD2*C;
                        C3 = app.A3 +  app.RA3*RD3*C;
    
                        % S1,S2,S3
                        u1 = C1 - app.RB1*app.B;
                        u2 = C2 - app.RB2*app.B;
                        u3 = C3 - app.RB3*app.B;
    
                        pos_real_time = parametric_circumcenter(u1,u2,u3,app.h);
                        if (isreal(pos_real_time(1)) && isreal(pos_real_time(2)) && isreal(pos_real_time(3)))
                            x_plot = cat(2,x_plot,pos_real_time(1));
                            y_plot = cat(2,y_plot,pos_real_time(2));
                            z_plot = cat(2,z_plot,-pos_real_time(3));
                        end
                        cartesian_error(1) = pos(1) - pos_real_time(1);
                        cartesian_error(2) = pos(2) - pos_real_time(2);
                        cartesian_error(3) = pos_real_time(3) - pos(3);
                        cartesian1_error_plot = cat(2,cartesian1_error_plot,cartesian_error(1));
                        cartesian2_error_plot = cat(2,cartesian2_error_plot,cartesian_error(2));
                        cartesian3_error_plot = cat(2,cartesian3_error_plot,cartesian_error(3));
                        
                        %% Update/pause visual plots
                        if ~app.plot_pause
                            app.UIAxes3_3.XLim = [time-17 time+8];
                            t1.XData = time_plot; t1.YData = theta1_plot;
                            t2.XData = time_plot; t2.YData = theta2_plot;
                            t3.XData = time_plot; t3.YData = theta3_plot;
                            app.UIAxes3_4.XLim = [time-17 time+8];
                            te1.XData = time_plot; te1.YData = theta1_error_plot;
                            te2.XData = time_plot; te2.YData = theta2_error_plot;
                            te3.XData = time_plot; te3.YData = theta3_error_plot;

                            app.UIAxes3_8.XLim = [time-17 time+8];
                            c1.XData = time_plot; c1.YData = x_plot;
                            c2.XData = time_plot; c2.YData = y_plot;
                            c3.XData = time_plot; c3.YData = z_plot;

                            app.UIAxes3_9.XLim = [time-17 time+8];
                            ce1.XData = time_plot; ce1.YData = cartesian1_error_plot;
                            ce2.XData = time_plot; ce2.YData = cartesian2_error_plot;
                            ce3.XData = time_plot; ce3.YData = cartesian3_error_plot;

                            XY_trajectory.XData = x_plot; XY_trajectory.YData = y_plot;
                            XZ_trajectory.XData = x_plot; XZ_trajectory.YData = z_plot;

                            app.UIAxes3_7.XLim = [time-17 time+8];
                            piro1.XData = time_plot; piro1.YData = pitch_plot;
                            piro2.XData = time_plot; piro2.YData = roll_plot;
                        end

                        %% Change plot mode
                        if app.MeasureButton.Value
                            t1.Visible = "on"; te1.Visible = "off";
                            t2.Visible = "on"; te2.Visible = "off";
                            t3.Visible = "on"; te3.Visible = "off";
                            c1.Visible = "on"; ce1.Visible = "off";
                            c2.Visible = "on"; ce2.Visible = "off";
                            c3.Visible = "on"; ce3.Visible = "off";
                            legend(app.UIAxes3_3,'show'); legend(app.UIAxes3_4,'hide');
                            legend(app.UIAxes3_8,'show'); legend(app.UIAxes3_9,'hide');
                        elseif app.ErrorButton.Value
                            t1.Visible = "off"; te1.Visible = "on";
                            t2.Visible = "off"; te2.Visible = "on";
                            t3.Visible = "off"; te3.Visible = "on";
                            c1.Visible = "off"; ce1.Visible = "on";
                            c2.Visible = "off"; ce2.Visible = "on";
                            c3.Visible = "off"; ce3.Visible = "on";
                            legend(app.UIAxes3_3,'hide'); legend(app.UIAxes3_4,'show');
                            legend(app.UIAxes3_8,'hide'); legend(app.UIAxes3_9,'show');
                        end
                    else
                        init_delay = init_delay+1;
                    end
                else
                    init_delay = 0;
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        focus(app.InputxEditField);

           % Send
            if ~(app.OutwardSerialEditField.Value == "Invalid position")
                app.StartButton.Enable = "off";
                % for i = 1:1:8
                    writeline(app.ESP_serial,app.OutwardSerialEditField.Value);
                %     pause(0.1);
                % end
            else
                app.ESPConsoleEditField.Value = " ";
            end
            app.StartButton.Enable = "on";
        end

        % Callback function
        function ResetButtonPushed(app, event)

        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
                % x = app.InputxEditField.Value;
                % y = app.InputyEditField.Value;
                % z = app.InputzEditField.Value;
                % incr = 6;          % number of increments of movement
                %% Input from user
                incr = app.NooflinesEditField.Value+1;
                
                %% ----------------------------------------------Under construction------------------------------------
                    % Currently only working with perfect circle on XY plane
                    
                %% ----------------------------------------------Under construction------------------------------------
                samp_endtime = 10;
                % samp_theta = linspace(0,2*pi,incr);
                samp_trajectory_theta = quintic_polynomial(0,2*pi,samp_endtime,incr);        % Quintic polynomial
                samp_theta = samp_trajectory_theta(1,:);
                samp_velocity = samp_trajectory_theta(2,:)*app.REditField.Value;
                samp_center = [0,0,13];
                if app.CircularButton.Value
                    x = samp_center(1) + app.REditField.Value * cos(samp_theta);
                elseif app.LinearButton.Value
                    x = linspace(app.InputxEditField.Value,app.EditField.Value,incr);
                end
                if app.CircularButton_2.Value
                    if app.CircularButton.Value
                        y = samp_center(2) + app.REditField_2.Value * sin(samp_theta);
                    else
                        y = samp_center(2) + app.REditField_2.Value * cos(samp_theta);
                    end
                elseif app.LinearButton_2.Value
                    y = linspace(app.InputyEditField.Value,app.EditField_2.Value,incr);
                end
                if app.CircularButton_3.Value
                    z = samp_center(3) + app.REditField_3.Value * sin(samp_theta);
                elseif app.LinearButton_3.Value
                    z = linspace(app.InputzEditField.Value,app.EditField_3.Value,incr);
                end
                
                % % Circular motion
                % samp_R1 = 4;
                % samp_R2 = 14;
                % % x = samp_center(1) + samp_R2 * cos(samp_theta);
                % y = samp_center(2) + samp_R2 * sin(samp_theta);
                % z = samp_center(3) + samp_R1 * sin(samp_theta);
                
                % Linear motion
                % x = linspace(0,1,incr);
                % y = linspace(-10,10,incr);
                % z = linspace(43,52,incr);
                
                focus(app.StartButton);

                % Initialize angle matrix
                theta_matrix = []; theta_dot_matrix = [];
                
                %% Parameters link length
                for k = 1:1:incr 
                    P = [x(k) y(k) -z(k)]';
    
                    %% Inverse Kinematics
                    % Parameters for theta2
                    U = -2 .* app.d .* (P(1) .*cos(app.alpha)+ P(2) .*sin(app.alpha) + app.r - app.R);
                    V = -2 * app.d * P(3);
                    W = P(1)^2 + P(2)^2 + P(3)^2 + app.d^2 - app.h^2 + (app.r - app.R)^2 +2.*(app.r - app.R).*(P(1) .*cos(app.alpha)+ P(2) .*sin(app.alpha));
                    % Calculate theta2
                    theta2a = 2*atan((-V + sqrt(V.^2 + U.^2 - W.^2))./(W - U));
                    theta2b = 2*atan((-V - sqrt(V.^2 + U.^2 - W.^2))./(W - U));
                    theta_sw = -theta2a;     %% Leg down
                    % theta_sw = -theta2b;     %% Leg up
                    angle = rad2deg(theta_sw);         % No need Offset as zero pose (Smol dog)
                    % angle = rad2deg(theta_sw) + 15;         % Offset as zero pose (Big dog)

                    invalid_check = 0;
                    if (~isreal(angle(1)) || ~isreal(angle(2)) || ~isreal(angle(3)) || angle(1)<0 || angle(2)<0 || angle(3)<0)
                        invalid_check = 1;
                        app.OutwardSerialEditField.Value = 'Invalid position';
                        break;
                    end

                    % Calculate D and E positions
                    C = [app.d,0,0]';
                    RD1 = [cos(theta_sw(1)), 0, sin(theta_sw(1));
                           0,  1, 0;
                           -sin(theta_sw(1)), 0, cos(theta_sw(1))];
                    RD2 = [cos(theta_sw(2)), 0, sin(theta_sw(2));
                           0,  1, 0;
                           -sin(theta_sw(2)), 0, cos(theta_sw(2))];
                    RD3 = [cos(theta_sw(3)), 0, sin(theta_sw(3));
                           0,  1, 0;
                           -sin(theta_sw(3)), 0, cos(theta_sw(3))];
                    C1 = app.A1 +  app.RA1*RD1*C;
                    C2 = app.A2 +  app.RA2*RD2*C;
                    C3 = app.A3 +  app.RA3*RD3*C;

                    B1 = P + app.RB1*app.B;
                    B2 = P + app.RB2*app.B;
                    B3 = P + app.RB3*app.B;

                    %% Jacobian
                    K = [(B1-C1)';(B2-C2)';(B3-C3)'];
                    J = zeros(3,3);
                    middle_matrix = [0,0,1;0,0,0;-1,0,0];       % derive vector d_i to theta_i
                    end_matrix1 = [app.d*cos(theta_sw(1));0;app.d*-sin(theta_sw(1))];
                    end_matrix2 = [app.d*cos(theta_sw(2));0;app.d*-sin(theta_sw(2))];
                    end_matrix3 = [app.d*cos(theta_sw(3));0;app.d*-sin(theta_sw(3))];
                    deriv_d1 = app.RA1*middle_matrix*end_matrix1;
                    J(1,1) = K(1,:)*deriv_d1;
                    deriv_d2 = app.RA2*middle_matrix*end_matrix2;
                    J(2,2) = K(2,:)*deriv_d2;
                    deriv_d3 = app.RA3*middle_matrix*end_matrix3;
                    J(3,3) = K(3,:)*deriv_d3;
                    
                    % Radial quintic speed
                    if k == incr
                        velocity_direction = [x(k)-x(k-1);y(k)-y(k-1);-z(k)+z(k-1)];
                    else
                        velocity_direction = [x(k+1)-x(k);y(k+1)-y(k);-z(k+1)+z(k)];
                    end
                    sample_speed = velocity_direction*samp_velocity(k)/norm(velocity_direction);
                    % sample_speed = sample_speed';
                
                    angle_rate = rad2deg(J\K*sample_speed)';        % Jacobian output motor velocity

                    % Concat angle matrix for ESP32
                    theta_matrix = [theta_matrix; angle];
                    theta_dot_matrix = [theta_dot_matrix;angle_rate];
                end

                %% Linking path
                % linking_column1 = transpose(linspace(app.theta_rec_send(1),theta_matrix(1,1),incr));
                % linking_column2 = transpose(linspace(app.theta_rec_send(2),theta_matrix(1,2),incr));
                % linking_column3 = transpose(linspace(app.theta_rec_send(3),theta_matrix(1,3),incr));
                samp_trajectory_m1 = quintic_polynomial(app.theta_rec_send(1),theta_matrix(1,1),samp_endtime,incr);        % Quintic polynomial
                m1_velocity = samp_trajectory_m1(2,:)';                                                                     % [Deg/s]
                samp_trajectory_m2 = quintic_polynomial(app.theta_rec_send(2),theta_matrix(1,2),samp_endtime,incr);        % Quintic polynomial
                m2_velocity = samp_trajectory_m2(2,:)';
                samp_trajectory_m3 = quintic_polynomial(app.theta_rec_send(3),theta_matrix(1,3),samp_endtime,incr);        % Quintic polynomial
                m3_velocity = samp_trajectory_m3(2,:)';
                linking_column1 = samp_trajectory_m1(1,:)';
                linking_column2 = samp_trajectory_m2(1,:)';
                linking_column3 = samp_trajectory_m3(1,:)';
                linking_matrix = [linking_column1,linking_column2,linking_column3];
                linking_velocity_matrix = [m1_velocity,m2_velocity,m3_velocity];
                theta_matrix = [linking_matrix;theta_matrix];
                theta_dot_matrix = [linking_velocity_matrix;theta_dot_matrix];
                data_matrix = [theta_matrix,theta_dot_matrix];
                
                linking_cartesian_matrix = [];
                linking_column1 = deg2rad(linking_column1);
                linking_column2 = deg2rad(linking_column2);
                linking_column3 = deg2rad(linking_column3);
                
                %% Linking cartesian simulation visual data
                for i = 1:1:incr
                    %% Forward Kinematics
                    % Calculate D and E positions
                    C = [app.d,0,0]';
                    RD1 = [cos(linking_column1(i)), 0, sin(linking_column1(i));
                           0,  1, 0;
                           -sin(linking_column1(i)), 0, cos(linking_column1(i))];
                    RD2 = [cos(linking_column2(i)), 0, sin(linking_column2(i));
                           0,  1, 0;
                           -sin(linking_column2(i)), 0, cos(linking_column2(i))];
                    RD3 = [cos(linking_column3(i)), 0, sin(linking_column3(i));
                           0,  1, 0;
                           -sin(linking_column3(i)), 0, cos(linking_column3(i))];
                    C1 = app.A1 +  app.RA1*RD1*C;
                    C2 = app.A2 +  app.RA2*RD2*C;
                    C3 = app.A3 +  app.RA3*RD3*C;

                    % S1,S2,S3
                    u1 = C1 - app.RB1*app.B;
                    u2 = C2 - app.RB2*app.B;
                    u3 = C3 - app.RB3*app.B;

                    pos = parametric_circumcenter(u1,u2,u3,app.h);
                    pos(3) = -pos(3);
                    linking_cartesian_matrix = [linking_cartesian_matrix;pos'];
                end
                x = [linking_cartesian_matrix(:,1)',x];
                y = [linking_cartesian_matrix(:,2)',y];
                z = [linking_cartesian_matrix(:,3)',z];
                
                %% Prepare data to ESP
                if invalid_check == 0
                    app.theta_export = data_matrix;
                    set(app.sample_trajectory, 'XData', x, ...
                            'YData', y, ...
                            'ZData', z)
                    drawnow limitrate;
                    % Single output
                        % output = string(angle(1)) + ', ' + string(angle(2)) + ', ' + string(angle(3));
                    % Matrix output
                        % temp_output = [1,2,3; 4,5,6; 7,8,9];
                        temp_output = reshape(data_matrix',1,[]);
                        output_number_of_elements = size(temp_output);
                        output = '';
                        for i = 1:1:output_number_of_elements(2)
                            output = output + string(temp_output(i)) + ', ';
                        end
                        output = output + string(incr*2);
                        output = convertStringsToChars(output);
                        % output(end) = '';output(end) = '';
                    app.OutwardSerialEditField.Value = output;

                    %% Angle per iteration (simulation) plot
                    app.sim_theta1.XData = (1:incr*2); app.sim_theta1.YData = theta_matrix(:,1);
                    app.sim_theta2.XData = (1:incr*2); app.sim_theta2.YData = theta_matrix(:,2);
                    app.sim_theta3.XData = (1:incr*2); app.sim_theta3.YData = theta_matrix(:,3);
                    
                    app.sim_cartesian1.XData = (1:incr*2); app.sim_cartesian1.YData = x;
                    app.sim_cartesian2.XData = (1:incr*2); app.sim_cartesian2.YData = y;
                    app.sim_cartesian3.XData = (1:incr*2); app.sim_cartesian3.YData = z;

                    app.sim_XY.XData = x; app.sim_XY.YData = y;
                    app.sim_XZ.XData = x; app.sim_XZ.YData = z;
                else
                    invalid_check = 0;
                end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed2(app, event)
            app.export = 1; 
            writematrix(app.theta_export,'Theta_data.xlsx','Sheet',1,'Range','A2');
            focus(app.StartButton);
        end

        % Selection changed function: InputxButtonGroup
        function InputxButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputxButtonGroup.SelectedObject;
            if selectedButton == app.CircularButton
                app.InputxEditField.Enable = 'off';
                app.EditField.Enable = 'off';
                app.REditField.Enable = 'on';
            else
                app.InputxEditField.Enable = 'on';
                app.EditField.Enable = 'on';
                app.REditField.Enable = 'off';
            end
        end

        % Selection changed function: InputyButtonGroup
        function InputyButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputyButtonGroup.SelectedObject;
            if selectedButton == app.CircularButton_2
                app.InputyEditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.REditField_2.Enable = 'on';
            else
                app.InputyEditField.Enable = 'on';
                app.EditField_2.Enable = 'on';
                app.REditField_2.Enable = 'off';
            end
        end

        % Selection changed function: InputzButtonGroup
        function InputzButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputzButtonGroup.SelectedObject;
            if selectedButton == app.CircularButton_3
                app.InputzEditField.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.REditField_3.Enable = 'on';
            else
                app.InputzEditField.Enable = 'on';
                app.EditField_3.Enable = 'on';
                app.REditField_3.Enable = 'off';
            end
        end

        % Selection changed function: RealtimemodeButtonGroup
        function RealtimemodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.RealtimemodeButtonGroup.SelectedObject;
            if selectedButton == app.ErrorButton
                app.UIAxes3_3.Visible = "off";
                app.UIAxes3_4.Visible = "on";
                app.UIAxes3_8.Visible = "off";
                app.UIAxes3_9.Visible = "on";
            else
                app.UIAxes3_3.Visible = "on";
                app.UIAxes3_4.Visible = "off";
                app.UIAxes3_8.Visible = "on";
                app.UIAxes3_9.Visible = "off";
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.plot_pause == 0
                app.plot_pause = 1;
                app.PauseButton.BackgroundColor = 'red';
                app.PauseButton.FontColor = 'white';
            else
                app.plot_pause = 0;
                app.PauseButton.BackgroundColor = [0.96,0.96,0.96];
                app.PauseButton.FontColor = 'black';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.simulation = uiaxes(app.UIFigure);
            xlabel(app.simulation, 'X (cm)')
            ylabel(app.simulation, 'Y (cm)')
            zlabel(app.simulation, 'Z (cm)')
            app.simulation.XLim = [-1 1];
            app.simulation.YLim = [-1 1];
            app.simulation.ZLim = [-1 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Motor angles per iteration')
            xlabel(app.UIAxes3, 'Iteration')
            ylabel(app.UIAxes3, 'Angle (degrees)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Cartesian trajectory per iteration')
            xlabel(app.UIAxes3_2, 'Iteration')
            ylabel(app.UIAxes3_2, 'Position (cm)')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Motor angles measured to time')
            xlabel(app.UIAxes3_3, 'Time (s)')
            ylabel(app.UIAxes3_3, 'Angle (degrees)')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.UIFigure);
            title(app.UIAxes3_4, 'Motor angles error to time')
            xlabel(app.UIAxes3_4, 'Time (s)')
            ylabel(app.UIAxes3_4, 'Angle (degrees)')
            zlabel(app.UIAxes3_4, 'Z')
            app.UIAxes3_4.Visible = 'off';
            app.UIAxes3_5 = uiaxes(app.UIFigure);
            title(app.UIAxes3_5, 'Trajectory in XY plane')
            xlabel(app.UIAxes3_5, 'X (cm)')
            ylabel(app.UIAxes3_5, 'Y (cm)')
            app.UIAxes3_6 = uiaxes(app.UIFigure);
            title(app.UIAxes3_6, 'Trajectory in XZ plane')
            xlabel(app.UIAxes3_6, 'X (cm)')
            ylabel(app.UIAxes3_6, 'Z (cm)')
            app.UIAxes3_7 = uiaxes(app.UIFigure);
            title(app.UIAxes3_7, 'Pitch and Roll measured to time')
            xlabel(app.UIAxes3_7, 'Time (s)')
            ylabel(app.UIAxes3_7, 'Angle (degrees)')
            app.UIAxes3_8 = uiaxes(app.UIFigure);
            title(app.UIAxes3_8, 'Catesian position measured to time')
            xlabel(app.UIAxes3_8, 'Time (s)')
            ylabel(app.UIAxes3_8, 'Position (cm)')
            zlabel(app.UIAxes3_8, 'Z')
            app.UIAxes3_9 = uiaxes(app.UIFigure);
            title(app.UIAxes3_9, 'Catesian position error to time')
            xlabel(app.UIAxes3_9, 'Time (s)')
            ylabel(app.UIAxes3_9, 'Position (cm)')
            zlabel(app.UIAxes3_9, 'Z')
            app.UIAxes3_9.Visible = 'off';
            app.InputxButtonGroup = uibuttongroup(app.UIFigure);
            app.InputxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputxButtonGroupSelectionChanged, true);
            app.InputxButtonGroup.Title = 'Input x';
            app.LinearButton = uiradiobutton(app.InputxButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.CircularButton = uiradiobutton(app.InputxButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.REditFieldLabel = uilabel(app.InputxButtonGroup);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.InputxButtonGroup, 'numeric');
            app.REditField.Value = 4;
            app.InputzButtonGroup = uibuttongroup(app.UIFigure);
            app.InputzButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputzButtonGroupSelectionChanged, true);
            app.InputzButtonGroup.Title = 'Input z';
            app.LinearButton_3 = uiradiobutton(app.InputzButtonGroup);
            app.LinearButton_3.Text = 'Linear';
            app.LinearButton_3.Value = true;
            app.CircularButton_3 = uiradiobutton(app.InputzButtonGroup);
            app.CircularButton_3.Text = 'Circular';
            app.InputzEditField = uieditfield(app.InputzButtonGroup, 'numeric');
            app.InputzEditField.AllowEmpty = 'on';
            app.InputzEditField.Value = 12;
            app.EditField_3Label = uilabel(app.InputzButtonGroup);
            app.EditField_3Label.Text = '-';
            app.EditField_3 = uieditfield(app.InputzButtonGroup, 'numeric');
            app.EditField_3.Value = 12;
            app.REditField_3Label = uilabel(app.InputzButtonGroup);
            app.REditField_3Label.Enable = 'off';
            app.REditField_3Label.Text = 'R';
            app.REditField_3 = uieditfield(app.InputzButtonGroup, 'numeric');
            app.REditField_3.Enable = 'off';
            app.REditField_3.Value = 3;
            app.InputyButtonGroup = uibuttongroup(app.UIFigure);
            app.InputyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputyButtonGroupSelectionChanged, true);
            app.InputyButtonGroup.Title = 'Input y';
            app.LinearButton_2 = uiradiobutton(app.InputyButtonGroup);
            app.LinearButton_2.Text = 'Linear';
            app.CircularButton_2 = uiradiobutton(app.InputyButtonGroup);
            app.CircularButton_2.Text = 'Circular';
            app.CircularButton_2.Value = true;
            app.InputyEditField = uieditfield(app.InputyButtonGroup, 'numeric');
            app.InputyEditField.AllowEmpty = 'on';
            app.InputyEditField.Enable = 'off';
            app.EditField_2Label = uilabel(app.InputyButtonGroup);
            app.EditField_2Label.Enable = 'off';
            app.EditField_2Label.Text = '-';
            app.EditField_2 = uieditfield(app.InputyButtonGroup, 'numeric');
            app.EditField_2.Enable = 'off';
            app.REditField_2Label = uilabel(app.InputyButtonGroup);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.InputyButtonGroup, 'numeric');
            app.REditField_2.Value = 4;
            app.OutwardSerialEditField = uieditfield(app.UIFigure, 'text');
            app.OutwardSerialEditField.Editable = 'off';
            app.OutwardSerialEditField.Value = '0.0000, 0.0000, 0.0000';
            app.InputxEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputxEditField.AllowEmpty = 'on';
            app.InputxEditField.Enable = 'off';
            app.OutwardSerialEditFieldLabel = uilabel(app.UIFigure);
            app.OutwardSerialEditFieldLabel.Text = 'Outward Serial';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = '-';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Enable = 'off';
            app.ESPConsoleEditField = uieditfield(app.UIFigure, 'text');
            app.ESPConsoleEditField.Editable = 'off';
            app.ESPConsoleEditFieldLabel = uilabel(app.UIFigure);
            app.ESPConsoleEditFieldLabel.Text = 'ESP Console';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed2, true);
            app.ExportButton.Text = 'Export';
            app.NooflinesEditFieldLabel = uilabel(app.UIFigure);
            app.NooflinesEditFieldLabel.Text = 'No. of lines';
            app.NooflinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NooflinesEditField.Value = 100;
            app.RealtimemodeButtonGroup = uibuttongroup(app.UIFigure);
            app.RealtimemodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RealtimemodeButtonGroupSelectionChanged, true);
            app.RealtimemodeButtonGroup.Title = 'Real-time mode';
            app.MeasureButton = uiradiobutton(app.RealtimemodeButtonGroup);
            app.MeasureButton.Text = 'Measure';
            app.MeasureButton.Value = true;
            app.ErrorButton = uiradiobutton(app.RealtimemodeButtonGroup);
            app.ErrorButton.Text = 'Error';
            app.PauseButton = uibutton(app.RealtimemodeButtonGroup, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CTECS_quintic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end